classdef self_balancing_robot_interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Button_StoreData         matlab.ui.control.Button
        Label_DataName           matlab.ui.control.Label
        EditField_DataName       matlab.ui.control.EditField
        Panel_ControlAction_2    matlab.ui.container.Panel
        Gauge_Linear             matlab.ui.control.SemicircularGauge
        Gauge_Angular            matlab.ui.control.SemicircularGauge
        Label_GaugeAngular       matlab.ui.control.Label
        Label_GaugeLinear        matlab.ui.control.Label
        Panel_Status             matlab.ui.container.Panel
        Label_BatteryValue       matlab.ui.control.Label
        Label_Battery            matlab.ui.control.Label
        Image_Battery            matlab.ui.control.Image
        Lamp_BluetoothStatus     matlab.ui.control.Lamp
        SerialLabel              matlab.ui.control.Label
        Panel_Bluetooth          matlab.ui.container.Panel
        DropDown_BluetoothPorts  matlab.ui.control.DropDown
        PortsDropDownLabel       matlab.ui.control.Label
        Button_BluetoothConnect  matlab.ui.control.Button
        Label_Tittle             matlab.ui.control.Label
        Image_ETSIDI             matlab.ui.control.Image
        Image_upm                matlab.ui.control.Image
        Graph_Pitch              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % -- Bluetooth LE manager --
        bluetooth_portList
        serial_port;
        bluetooth_status;
        bluetooth_available;
        serial_baudrate;
        serial_firstConnection;

        % -- Serial message --
        msg_totalParameters = 4;
        msg_sampleTime = 0.250;    % 250ms = 0.25s

        msg_Battery;
        msg_Pitch;
        msg_LinearVel;
        msg_AngularVel;

        % -- Graphic values --
        vector_Time = [0];
        vector_Pitch = [0];

    end
    
    methods (Access = private)
        
        function initializeBluetooth(app)
            available_BLE = blelist;
            
            if isempty(available_BLE)
                disp('There are no bluetooth ports available');
                app.bluetooth_available = false;

                % Disable the combo box options
                app.DropDown_BluetoothPorts.Items = {};
                
                % Change the name of the connection button
                app.Button_BluetoothConnect.Text = 'Refresh';
            else
                app.bluetooth_portList = string(available_BLE.Name);
                disp('Available serial ports:');
                disp(app.bluetooth_portList);

                % Activate the connection button
                app.Button_BluetoothConnect.Text = 'Connect';

                % Complete the combo box with the available ports
                app.DropDown_BluetoothPorts.Items = app.bluetooth_portList;
            end
        end


        function initializeWidgets(app)
            % -- Graphics --
            %ylim(app.UIAxes, [-360, 360]);
            %xlim(app.UIAxes, [0, 10]);

            % -- Others --
            app.Lamp_BluetoothStatus.Color = 'r';
            app.EditField_DataName.Enable = false;
            app.EditField_DataName.Visible = false;
            app.Label_DataName.Visible = false;

        end


        function initializeProperties(app)
            app.serial_firstConnection = true;
            app.bluetooth_status = false;
            app.serial_baudrate = 115200;

            % Serial message parameters
            app.msg_Battery = 0;
            app.msg_Pitch = 0;
            app.msg_LinearVel = 0;
            app.msg_AngularVel = 0;
        end


        function manageSerialMessage(app)
            % Read the serial port data
            flush(app.serial_port);
            serial_data = readline(app.serial_port);
                    
            % Split the data by the comma
            split_serial_data = split(serial_data, ',');
                    
            % Check if the data was properly split into two parts
            if numel(split_serial_data) == app.msg_totalParameters
                % Verify if the data is valid
                if app.checkCorrectMsg(app,split_serial_data)
                    % Update the data 
                    app.updateMsg(app);

                    % Update the labels
                    app.manageSerialMsg_Battery(app);

                    % Update the Gauges
                    app.manageSerialMsg_LinearVel(app);
                    app.manageSerialMsg_AngularVel(app);
                    
                    % Update the Graphics

                
                else
                    % Log a warning if the received data is invalid
                    disp('Invalid numeric data received: %s', serial_data);
                end
            else
                % Log a warning if the data is not in the expected format
                disp('Invalid serial data format: %s', serial_data);
            end
        end


        function updateMsg(app,split_msg)
            % Update the fields in the Data property
            app.msg_Battery = str2double(split_msg{1});  
            app.msg_Pitch = str2double(split_msg{2});
            app.msg_LinearVel = str2double(split_msg{3});
            app.msg_AngularVel = str2double(split_msg{4});
        end


        function manageSerialMsg_LinearVel(app)
            if isfinite(app.msg_LinearVel)
                app.Gauge_Linear.Value = app.msg_LinearVel;
            else
                app.Gauge_Linear.Value = 0;
                disp('Invalid Linear Velocity value received. Setting gauge to default value (0).');
            end
        end


        function manageSerialMsg_AngularVel(app)
            if isfinite(app.msg_AngularVel)
                app.Gauge_Angular.Value = app.msg_AngularVel;
            else
                app.Gauge_Linear.Value = 0;
                disp('Invalid Angular Velocity value received. Setting gauge to default value (0).');
            end
        end


        function manageSerialMsg_Battery(app)
            if isfinite(app.msg_battery)
                app.Label_BatteryValue.Text = app.msg_battery;
            else
                app.Label_BatteryValue.Text = X; 
                disp('Invalid Battery command value received. Setting gauge to default value (0).');
            end
        end


        function result = checkCorrectMsg(app, split_msg)
            result = true;
            for i = 1:app.msg_totalParameters
                if isnan(split_msg{i})
                    result = false;
                    return;
                end
            end
        end


        function updateWidgets(app)
            % Update Gauges
            app.manageSerialMsg_Battery();
            app.manageSerialMsg_LinearVel();
            app.manageSerialMsg_AngularVel();

            app.updateGraphs();
        end


        function updateGraphs(app)
            % Ensure data is numeric and dimensions match
            if app.updateGraphValues()
                % Update the Pitch graph
                plot(app.Graph_Pitch, app.vector_Time, app.vector_Pitch, 'b-', 'LineWidth', 1.5);
                app.Graph_Pitch.XLabel.String = 'Time';
                app.Graph_Pitch.YLabel.String = 'Pitch';
                app.Graph_Pitch.Title.String = 'Pitch vs Time';
            
                % Refresh display
                drawnow;
            end
        end


        function returned = updateGraphValues(app)
        
            if isnan(app.msg_pitch) 
                warning('msg_pitch value is not valid');
                returned = false;
                return;
            end
      
        
            % Add the new values
            app.vector_Time(end+1) = app.msg_time;
            app.vector_Pitch(end+1) = app.msg_Pitch;
        
            % Ensure that two vectors have the same length
           if length(app.vector_Time) ~= length(app.vector_Pitch)
                warning('Time and Pitch vector dont have the same length');
                returned = false;
                return;
            end

            returned = true;
        
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            initializeWidgets(app);
            
            initializeBluetooth(app);

            initializeProperties(app);


        end

        % Button pushed function: Button_BluetoothConnect
        function Button_BluetoothConnectPushed(app, event)
            if app.Button_BluetoothConnect.Text == "Refresh"
                disp('Refreshing serial port connections...');
                initializeBluetooth(app);
            else

                % Activate status to true
                app.bluetooth_status = true;
                app.Lamp_BluetoothStatus.Color = 'g';

                % Activate Data Widgets
                app.EditField_DataName.Visible = false;
                app.Button_StoreData.Visible = false;
                app.Label_DataName.Visible = false;
                    
                while(app.bluetooth_status == true)
                    % Read the serial port data
                    flush(app.serial_port);
                    serial_data = readline(app.serial_port);
                            
                    % Split the data by the comma
                    split_serial_data = split(serial_data, ',');
                            
                    % Check if the data was properly split into two parts
                    if numel(split_serial_data) == app.msg_totalParameters
                        % Convert and assign the data                
                        % Verify if the data is valid
                        if app.checkCorrectMsg(split_serial_data)  % No se pasa 'app', solo 'split_serial_data'
                            app.updateMsg(split_serial_data);
                            %app.updateData(app);
                            app.updateWidgets();
                        else
                            % Handle invalid data (e.g., log or notify)
                            disp('Invalid numeric data received.');
                        end
                    else
                        % Handle case where the number of split parts is incorrect
                        disp('Invalid serial data format.');
                    end
               end
            end
        end

        % Callback function
        function Button_SaveDataPushed(app, event)
            if ~isempty(app.Data)
                % Save the data in the workspace
                assignin('base', app.EditField_DataName.Value, app.Data);
                disp('Data saved to workspace with name: %s\n', app.EditField_DataName.Value);

                % Close the app
                delete(app.UIFigure);
            else
                disp('No data available to save.');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Graph_Pitch = uiaxes(app.UIFigure);
            xlabel(app.Graph_Pitch, 'Time  [s]')
            ylabel(app.Graph_Pitch, 'Pitch  [deg]')
            zlabel(app.Graph_Pitch, 'Z')
            app.Image_upm = uiimage(app.UIFigure);
            app.Image_upm.ImageSource = fullfile(pathToMLAPP, 'upm_logo.png');
            app.Image_ETSIDI = uiimage(app.UIFigure);
            app.Image_ETSIDI.ImageSource = fullfile(pathToMLAPP, 'etsidi.png');
            app.Label_Tittle = uilabel(app.UIFigure);
            app.Label_Tittle.Text = 'SELF-BALANCING ROBOT INTERFACE';
            app.Panel_Bluetooth = uipanel(app.UIFigure);
            app.Panel_Bluetooth.Title = 'BLE Connection';
            app.Button_BluetoothConnect = uibutton(app.Panel_Bluetooth, 'push');
            app.Button_BluetoothConnect.ButtonPushedFcn = createCallbackFcn(app, @Button_BluetoothConnectPushed, true);
            app.Button_BluetoothConnect.Text = 'Connect';
            app.PortsDropDownLabel = uilabel(app.Panel_Bluetooth);
            app.PortsDropDownLabel.Text = 'Ports';
            app.DropDown_BluetoothPorts = uidropdown(app.Panel_Bluetooth);
            app.Panel_Status = uipanel(app.UIFigure);
            app.Panel_Status.Title = 'Status';
            app.SerialLabel = uilabel(app.Panel_Status);
            app.SerialLabel.Text = 'Bluetooth';
            app.Lamp_BluetoothStatus = uilamp(app.Panel_Status);
            app.Image_Battery = uiimage(app.Panel_Status);
            app.Image_Battery.ImageSource = fullfile(pathToMLAPP, 'battery.png');
            app.Label_Battery = uilabel(app.Panel_Status);
            app.Label_Battery.Text = 'Battery';
            app.Label_BatteryValue = uilabel(app.Panel_Status);
            app.Label_BatteryValue.Text = '0%';
            app.Panel_ControlAction_2 = uipanel(app.UIFigure);
            app.Panel_ControlAction_2.Title = 'Robot Velocity';
            app.Label_GaugeLinear = uilabel(app.Panel_ControlAction_2);
            app.Label_GaugeLinear.Text = 'Linear';
            app.Label_GaugeAngular = uilabel(app.Panel_ControlAction_2);
            app.Label_GaugeAngular.Text = 'Angular';
            app.Gauge_Angular = uigauge(app.Panel_ControlAction_2, 'semicircular');
            app.Gauge_Angular.Limits = [-10 10];
            app.Gauge_Linear = uigauge(app.Panel_ControlAction_2, 'semicircular');
            app.Gauge_Linear.Limits = [-10 10];
            app.EditField_DataName = uieditfield(app.UIFigure, 'text');
            app.Label_DataName = uilabel(app.UIFigure);
            app.Label_DataName.Text = 'Data Name';
            app.Button_StoreData = uibutton(app.UIFigure, 'push');
            app.Button_StoreData.Text = 'Store Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = self_balancing_robot_interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end