classdef serial_motor_control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Gauge_ControlAction      matlab.ui.control.LinearGauge
        ControlActionPWMLabel    matlab.ui.control.Label
        SERIALMOTORCONTROLLabel  matlab.ui.control.Label
        DisconnectButton         matlab.ui.control.Button
        ConnectButton            matlab.ui.control.Button
        DropDown                 matlab.ui.control.DropDown
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        serial_port;
        connect_status;
        first_connection;
        data_idx;
        serial_angle;
        serial_controlAction;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Complete the combo box with the available ports
            portList = serialportlist;
            app.DropDown.Items = portList;

            % Configure the gauge
            app.Gauge_ControlAction.Limits = [-255 255];
            app.Gauge_ControlAction.MajorTicks = -255:100:255;
            app.Gauge_ControlAction.MinorTicks = -255:50:255;

            % Configure the graphic
            %ylim(app.UIAxes, [-360, 360]);
            %xlim(app.UIAxes, [0, 10]);

            % Initialize attributes
            app.first_connection = true;
            app.connect_status = false;
            app.data_idx=1;
            app.serial_angle = 0;
            app.serial_controlAction = 0;
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)

        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            % Activate status to true
            app.connect_status = true;

            if(app.first_connection == true)
                % Connect to the serial port selected in the comboBox
                app.serial_port=serialport(app.DropDown.Value,115200);
                configureTerminator(app.serial_port,"CR/LF");
                app.first_connection = false;
            end

            % Initialize the data for the plot before entering the loop
            angles = []; % Stores angle values for the plot
            idx = [];    % Stores indices (time or iterations)
            
            while(app.connect_status == true)
                % Read the serial port data
                flush(app.serial_port);
                serial_data = readline(app.serial_port);
            
                % Split the data by the comma
                split_serial_data = split(serial_data, ',');
            
                % Check if the data was properly split into two parts
                if numel(split_serial_data) == 2
                    % Convert and assign the data
                    app.serial_controlAction = str2double(split_serial_data{1});  
                    app.serial_angle = str2double(split_serial_data{2});
            
                    % Verify if the data is valid
                    if ~isnan(app.serial_angle) && ~isnan(app.serial_controlAction)
                        % Update the data for the plot
                        angles(end+1) = app.serial_angle; % Append the angle to the list
                        idx(end+1) = app.data_idx;        % Append the index
            
                        % Update the plot instead of creating a new one
                        plot(app.UIAxes, angles);
                        drawnow; % Force the plot to update immediately
            
                        % Update the Gauge
                        if isfinite(app.serial_controlAction)
                            app.Gauge_ControlAction.Value = app.serial_controlAction;
                        else
                            app.Gauge_ControlAction.Value = 0; % Default value for invalid data
                            warning('Invalid control action value received. Setting gauge to default value (0).');
                        end
                    else
                        % Log a warning if the received data is invalid
                        warning('Invalid numeric data received: %s', serial_data);
                    end
                else
                    % Log a warning if the data is not in the expected format
                    warning('Invalid serial data format: %s', serial_data);
                end
            
                % Pause to simulate the sampling frequency
                pause(0.1);
            end

        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.connect_status = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'MOTOR ')
            xlabel(app.UIAxes, 'Time [s]')
            ylabel(app.UIAxes, 'Angle [deg]')
            zlabel(app.UIAxes, 'Z')
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.SERIALMOTORCONTROLLabel = uilabel(app.UIFigure);
            app.SERIALMOTORCONTROLLabel.Text = 'SERIAL MOTOR CONTROL';
            app.ControlActionPWMLabel = uilabel(app.UIFigure);
            app.ControlActionPWMLabel.Text = 'Control Action [PWM]';
            app.Gauge_ControlAction = uigauge(app.UIFigure, 'linear');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = serial_motor_control

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end