classdef self_balancing_robot_interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Label_DataName         matlab.ui.control.Label
        EditField_DataName     matlab.ui.control.EditField
        Button_SaveData        matlab.ui.control.Button
        Panel_Status           matlab.ui.container.Panel
        Gauge_Battery          matlab.ui.control.Gauge
        BatteryGaugeLabel      matlab.ui.control.Label
        Lamp_RcModeStatus      matlab.ui.control.Lamp
        SerialLabel_2          matlab.ui.control.Label
        Lamp_SerialStatus      matlab.ui.control.Lamp
        SerialLabel            matlab.ui.control.Label
        Panel_ControlAction    matlab.ui.container.Panel
        Gauge_LeftWheel        matlab.ui.control.LinearGauge
        LabelGauge_RightWheel  matlab.ui.control.Label
        LabelGauge_LeftWheel   matlab.ui.control.Label
        Gauge_RightWheel       matlab.ui.control.LinearGauge
        Panel_UserCommands     matlab.ui.container.Panel
        Gauge_Yaw              matlab.ui.control.LinearGauge
        LabelGauge_Yaw         matlab.ui.control.Label
        LabelGauge_Throttle    matlab.ui.control.Label
        Gauge_Throttle         matlab.ui.control.LinearGauge
        Panel_Serial           matlab.ui.container.Panel
        DropDown_SerialPorts   matlab.ui.control.DropDown
        PortsDropDownLabel     matlab.ui.control.Label
        Button_SerialConnect   matlab.ui.control.Button
        Label_Tittle           matlab.ui.control.Label
        Image_ETSIDI           matlab.ui.control.Image
        Image_upm              matlab.ui.control.Image
        Graph_Pitch            matlab.ui.control.UIAxes
        Graph_Yaw              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % -- Serial manager --
        serial_portList
        serial_port;
        serial_status;
        serial_available;
        serial_baudrate;
        serial_firstConnection;

        % -- Serial message --
        msg_totalParameters = 9;
        msg_sampleTime = 0.5;    % 500ms = 0.5s

        msg_time;
        msg_RcMode;
        msg_commandThrottle;
        msg_commandYaw;
        msg_battery;
        msg_pitch;
        msg_yaw;
        msg_leftWheel;
        msg_rightWheel;

        % -- Graphic values --
        vector_Time = [0];
        vector_Yaw = [0];
        vector_Pitch = [0];


        Data struct = struct('time', [], 'RcMode', [], 'commandThrottle', [], 'commandYaw', [], 'battery', [], ...
                          'pitch', [], 'yaw', [], 'leftWheel', [], 'rightWheel', []);
    end
    
    methods (Access = private)
        
        function initializeSerial(app)
            app.serial_portList = serialportlist;

            if isempty(app.serial_portList)
                disp('There are no serial port available');
                app.serial_available = false;

                % Disable the combo box options
                app.DropDown_SerialPorts.Items = {};
                
                % Change the name of the connection button
                app.Button_SerialConnect.Text = 'Refresh';
            else
                disp('Available serial ports:');
                disp(app.serial_portList);

                % Activate the connection button
                app.Button_SerialConnect.Text = 'Connect';

                % Complete the combo box with the available ports
                app.DropDown_SerialPorts.Items = app.serial_portList;
            end
        end


        function initializeWidgets(app)
            % -- Gauges --
            app.Gauge_Throttle.Limits = [-100 100];
            app.Gauge_Yaw.Limits = [-100 100];

            % -- Graphics --
            %ylim(app.UIAxes, [-360, 360]);
            %xlim(app.UIAxes, [0, 10]);

            % -- Lamps --
            app.Lamp_SerialStatus.Color = 'r';
            app.Lamp_RcModeStatus.Color = 'r';

            % -- Data Widgets --
            app.EditField_DataName.Visible = false;
            app.Button_SaveData.Visible = false;
            app.Label_DataName.Visible = false;

        end


        function initializeProperties(app)
            app.serial_firstConnection = true;
            app.serial_status = false;
            app.serial_baudrate = 115200;

            % Serial message parameters
            app.msg_time = 0;
            app.msg_battery = 0;
            app.msg_commandThrottle = 0;
            app.msg_commandYaw = 0;
            app.msg_leftWheel = 0;
            app.msg_rightWheel = 0;
            app.msg_pitch = 0;
            app.msg_yaw = 0;
            app.msg_RcMode = 0;
        end


        function manageFirstSerialConnection(app)
            % Connect to the serial port selected in the comboBox
            app.serial_port=serialport(app.DropDown_SerialPorts.Value,app.serial_baudrate);
            configureTerminator(app.serial_port,"CR/LF");
            app.serial_firstConnection = false;
        end


        function manageSerialMessage(app)
            % Read the serial port data
            flush(app.serial_port);
            serial_data = readline(app.serial_port);
                    
            % Split the data by the comma
            split_serial_data = split(serial_data, ',');
                    
            % Check if the data was properly split into two parts
            if numel(split_serial_data) == app.msg_totalParameters
                % Convert and assign the data
                app.msg_commandYaw = str2double(split_serial_data{1});  
                app.msg_commandThrottle = str2double(split_serial_data{2});
                app.msg_RcMode = str2double(split_serial_data{3});
        
                % Verify if the data is valid
                if app.checkCorrectMsg(app,split_serial_data)
                    % Update the data 
                    app.updateMsg(app);
                    app.updateData(app,split_serial_data);

                    % Update the status
                    app.manageSerialMsg_RCMode(app);

                    % Update the Gauges
                    app.manageSerialMsg_CommandYaw(app);
                    app.manageSerialMsg_CommandThrottle(app);
                    app.manageSerialMsg_Battery(app);
                    
                    % Update the Graphics

                
                else
                    % Log a warning if the received data is invalid
                    disp('Invalid numeric data received: %s', serial_data);
                end
            else
                % Log a warning if the data is not in the expected format
                disp('Invalid serial data format: %s', serial_data);
            end
        end


        function updateMsg(app,split_msg)
            % Update the fields in the Data property
            app.msg_time = str2double(split_msg{1});
            app.msg_battery = str2double(split_msg{2});
            app.msg_commandThrottle = str2double(split_msg{3});
            app.msg_commandYaw = str2double(split_msg{4});
            app.msg_RcMode = str2double(split_msg{5});
            app.msg_pitch = str2double(split_msg{6});
            app.msg_yaw = str2double(split_msg{7});
            app.msg_leftWheel = str2double(split_msg{8});
            app.msg_rightWheel = str2double(split_msg{9});
        end


        function updateData(app)
            app.Data.time = app.msg_time;
            app.Data.battery = app.msg_battery;
            app.Data.commandThrottle = app.msg_commandThrottle;
            app.Data.commandYaw = app.msg_commandYaw;
            app.Data.pitch = app.msg_pitch;
            app.Data.yaw = app.msg_yaw;
            app.Data.left = app.msg_leftWheel;
            app.Data.right = app.msg_rightWheel;
        end


        function manageSerialMsg_RCMode(app)
            if ~isnan(app.msg_RcMode)
                if isfinite(app.msg_RcMode)
                    if app.msg_RcMode==1
                        app.Lamp_RcModeStatus.Color = 'g';
                    else
                        app.Lamp_RcModeStatus.Color = 'r';
                    end
                else
                    app.Lamp_RcModeStatus.Color = 'r'; 
                    disp('Invalid RC Mode value received. Setting gauge to default value (0).');
                end
            else
            end
        end


        function manageSerialMsg_CommandYaw(app)
            if isfinite(app.msg_commandYaw)
                app.Gauge_Yaw.Value = app.msg_commandYaw;
            else
                app.Gauge_Yaw.Value = 0;
                disp('Invalid Yaw command value received. Setting gauge to default value (0).');
            end
        end


        function manageSerialMsg_CommandThrottle(app)
            if isfinite(app.msg_commandThrottle)
                app.Gauge_Throttle.Value = app.msg_commandThrottle;
            else
                app.Gauge_Throttle.Value = 0; 
                disp('Invalid Throttle command value received. Setting gauge to default value (0).');
            end
        end


        function manageSerialMsg_Battery(app)
            if isfinite(app.msg_battery)
                app.Gauge_Battery.Value = app.msg_battery;
            else
                app.Gauge_Battery.Value = 0; 
                disp('Invalid Battery command value received. Setting gauge to default value (0).');
            end
        end


        function result = checkCorrectMsg(app, split_msg)
            result = true;
            for i = 1:app.msg_totalParameters
                if isnan(split_msg{i})
                    result = false;
                    return;
                end
            end
        end


        function updateWidgets(app)
            app.updateGauges();
            app.updateGraphs();
        end


        function updateGauges(app)

            % Check if the conversion was successful
            if ~isnan(app.msg_commandYaw)
                app.Gauge_Yaw.Value = app.msg_commandYaw;
            else
                disp('Invalid yaw value');
            end
        
            if ~isnan(app.msg_commandThrottle)
                app.Gauge_Throttle.Value = app.msg_commandThrottle;
            else
                disp('Invalid throttle value');
            end

            if ~isnan(app.msg_RcMode)
                if app.msg_RcMode == 1
                    app.Lamp_RcModeStatus.Color = 'g';
                else
                    app.Lamp_RcModeStatus.Color = 'r';
                end
            else
                disp('Invalid RcMode value');
            end

            if ~isnan(app.msg_leftWheel)
                app.Gauge_LeftWheel.Value = app.msg_leftWheel;
            else
                disp('Invalid Left Wheel value');
            end

            if ~isnan(app.msg_rightWheel)
                app.Gauge_RightWheel.Value = app.msg_rightWheel;
            else
                disp('Invalid Right Wheel value');
            end
        end


        function updateGraphs(app)
            % Ensure data is numeric and dimensions match
            if app.updateGraphValues()
                % Update the Pitch graph
                plot(app.Graph_Pitch, app.vector_Time, app.vector_Pitch, 'b-', 'LineWidth', 1.5);
                app.Graph_Pitch.XLabel.String = 'Time';
                app.Graph_Pitch.YLabel.String = 'Pitch';
                app.Graph_Pitch.Title.String = 'Pitch vs Time';
            
                % Update the Yaw graph
                plot(app.Graph_Yaw, app.vector_Time, app.vector_Yaw, 'r-', 'LineWidth', 1.5);
                app.Graph_Yaw.XLabel.String = 'Time';
                app.Graph_Yaw.YLabel.String = 'Yaw';
                app.Graph_Yaw.Title.String = 'Yaw vs Time';
            
                % Refresh display
                drawnow;
            end
        end


        function returned = updateGraphValues(app)
        
            if isnan(app.msg_pitch) || isnan(app.msg_yaw)
                warning('msg_pitch o msg_yaw no son valores numéricos válidos.');
                returned = false;
                return;
            end
      
        
            % Add the new values
            app.vector_Time(end+1) = app.msg_time;
            app.vector_Pitch(end+1) = app.msg_pitch;
            app.vector_Yaw(end+1) = app.msg_yaw;
        
            % Ensure that two vectors have the same length
           if length(app.vector_Time) ~= length(app.vector_Pitch) || length(app.vector_Time) ~= length(app.vector_Yaw)
                warning('Time and Pitch vector dont have the same length');
                returned = false;
                return;
            end

            returned = true;
        
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            initializeSerial(app);

            initializeWidgets(app);

            initializeProperties(app);


        end

        % Button pushed function: Button_SerialConnect
        function Button_SerialConnectPushed(app, event)
            if app.Button_SerialConnect.Text == "Refresh"
                disp('Refreshing serial port connections...');
                initializeSerial(app);
            else
                if(app.serial_firstConnection == true)
                    app.manageFirstSerialConnection();
                end

                % Activate status to true
                app.serial_status = true;
                app.Lamp_SerialStatus.Color = 'g';

                % Activate Data Widgets
                app.EditField_DataName.Visible = false;
                app.Button_SaveData.Visible = false;
                app.Label_DataName.Visible = false;
                    
                while(app.serial_status == true)
                    % Read the serial port data
                    flush(app.serial_port);
                    serial_data = readline(app.serial_port);
                            
                    % Split the data by the comma
                    split_serial_data = split(serial_data, ',');
                            
                    % Check if the data was properly split into two parts
                    if numel(split_serial_data) == app.msg_totalParameters
                        % Convert and assign the data                
                        % Verify if the data is valid
                        if app.checkCorrectMsg(split_serial_data)  % No se pasa 'app', solo 'split_serial_data'
                            app.updateMsg(split_serial_data);
                            %app.updateData(app);
                            app.updateWidgets();
                        else
                            % Handle invalid data (e.g., log or notify)
                            disp('Invalid numeric data received.');
                        end
                    else
                        % Handle case where the number of split parts is incorrect
                        disp('Invalid serial data format.');
                    end
               end
            end
        end

        % Button pushed function: Button_SaveData
        function Button_SaveDataPushed(app, event)
            if ~isempty(app.Data)
                % Save the data in the workspace
                assignin('base', app.EditField_DataName.Value, app.Data);
                disp('Data saved to workspace with name: %s\n', app.EditField_DataName.Value);

                % Close the app
                delete(app.UIFigure);
            else
                disp('No data available to save.');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Graph_Yaw = uiaxes(app.UIFigure);
            xlabel(app.Graph_Yaw, 'Time [s]')
            ylabel(app.Graph_Yaw, 'Yaw [deg]')
            zlabel(app.Graph_Yaw, 'Z')
            app.Graph_Pitch = uiaxes(app.UIFigure);
            xlabel(app.Graph_Pitch, 'Time [s]')
            ylabel(app.Graph_Pitch, 'Pitch [deg]')
            zlabel(app.Graph_Pitch, 'Z')
            app.Image_upm = uiimage(app.UIFigure);
            app.Image_upm.ImageSource = fullfile(pathToMLAPP, 'upm_logo.png');
            app.Image_ETSIDI = uiimage(app.UIFigure);
            app.Image_ETSIDI.ImageSource = fullfile(pathToMLAPP, 'etsidi.png');
            app.Label_Tittle = uilabel(app.UIFigure);
            app.Label_Tittle.Text = 'SELF-BALANCING ROBOT INTERFACE';
            app.Panel_Serial = uipanel(app.UIFigure);
            app.Panel_Serial.Title = 'Serial Connection';
            app.Button_SerialConnect = uibutton(app.Panel_Serial, 'push');
            app.Button_SerialConnect.ButtonPushedFcn = createCallbackFcn(app, @Button_SerialConnectPushed, true);
            app.Button_SerialConnect.Text = 'Connect';
            app.PortsDropDownLabel = uilabel(app.Panel_Serial);
            app.PortsDropDownLabel.Text = 'Ports';
            app.DropDown_SerialPorts = uidropdown(app.Panel_Serial);
            app.Panel_UserCommands = uipanel(app.UIFigure);
            app.Panel_UserCommands.Title = 'User Commands';
            app.Gauge_Throttle = uigauge(app.Panel_UserCommands, 'linear');
            app.Gauge_Throttle.Limits = [-100 100];
            app.Gauge_Throttle.MajorTicks = [-100 -50 0 50 100];
            app.Gauge_Throttle.MajorTickLabels = {'-100', '-50', '0', '50', '100'};
            app.LabelGauge_Throttle = uilabel(app.Panel_UserCommands);
            app.LabelGauge_Throttle.Text = 'Throttle';
            app.LabelGauge_Yaw = uilabel(app.Panel_UserCommands);
            app.LabelGauge_Yaw.Text = 'Yaw';
            app.Gauge_Yaw = uigauge(app.Panel_UserCommands, 'linear');
            app.Gauge_Yaw.Limits = [-100 100];
            app.Gauge_Yaw.MajorTicks = [-100 -50 0 50 100];
            app.Gauge_Yaw.MajorTickLabels = {'-100', '-50', '0', '50', '100'};
            app.Panel_ControlAction = uipanel(app.UIFigure);
            app.Panel_ControlAction.Title = 'Control Action';
            app.Gauge_RightWheel = uigauge(app.Panel_ControlAction, 'linear');
            app.Gauge_RightWheel.Limits = [-255 255];
            app.Gauge_RightWheel.MajorTicks = [-255 -125 0 125 255];
            app.LabelGauge_LeftWheel = uilabel(app.Panel_ControlAction);
            app.LabelGauge_LeftWheel.Text = 'Left Wheel';
            app.LabelGauge_RightWheel = uilabel(app.Panel_ControlAction);
            app.LabelGauge_RightWheel.Text = 'Right Wheel';
            app.Gauge_LeftWheel = uigauge(app.Panel_ControlAction, 'linear');
            app.Gauge_LeftWheel.Limits = [-255 255];
            app.Gauge_LeftWheel.MajorTicks = [-255 -125 0 125 255];
            app.Panel_Status = uipanel(app.UIFigure);
            app.Panel_Status.Title = 'Status';
            app.SerialLabel = uilabel(app.Panel_Status);
            app.SerialLabel.Text = 'Serial';
            app.Lamp_SerialStatus = uilamp(app.Panel_Status);
            app.SerialLabel_2 = uilabel(app.Panel_Status);
            app.SerialLabel_2.Text = 'RC Mode';
            app.Lamp_RcModeStatus = uilamp(app.Panel_Status);
            app.BatteryGaugeLabel = uilabel(app.Panel_Status);
            app.BatteryGaugeLabel.Text = 'Battery';
            app.Gauge_Battery = uigauge(app.Panel_Status, 'circular');
            app.Button_SaveData = uibutton(app.UIFigure, 'push');
            app.Button_SaveData.ButtonPushedFcn = createCallbackFcn(app, @Button_SaveDataPushed, true);
            app.Button_SaveData.Text = 'Save';
            app.EditField_DataName = uieditfield(app.UIFigure, 'text');
            app.Label_DataName = uilabel(app.UIFigure);
            app.Label_DataName.Text = 'Data Name';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = self_balancing_robot_interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end